classdef TPMS_Designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ResetButton                   matlab.ui.control.Button
        ProcessDataButton             matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        v2EditField                   matlab.ui.control.NumericEditField
        v2EditFieldLabel              matlab.ui.control.Label
        Isovaluesv1EditField          matlab.ui.control.NumericEditField
        Isovaluesv1Label              matlab.ui.control.Label
        VolumeFractionSliderLabel     matlab.ui.control.Label
        VolumeFractionSlider          matlab.ui.control.Slider
        vftext                        matlab.ui.control.NumericEditField
        SeedNodesSlider               matlab.ui.control.Slider
        SeedNodesSliderLabel          matlab.ui.control.Label
        ntext                         matlab.ui.control.NumericEditField
        TypeDropDownLabel             matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
        EquationText                  matlab.ui.control.TextArea
        DesignDropDownLabel           matlab.ui.control.Label
        DesignDropDown                matlab.ui.control.DropDown
        TransformationsTab            matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        CellSizemmPanel               matlab.ui.container.Panel
        GridLayout9_3                 matlab.ui.container.GridLayout
        LzSlider                      matlab.ui.control.Slider
        LzSliderLabel                 matlab.ui.control.Label
        LySlider                      matlab.ui.control.Slider
        LySliderLabel                 matlab.ui.control.Label
        LxSlider                      matlab.ui.control.Slider
        LxSliderLabel                 matlab.ui.control.Label
        RotationsdegreesPanel         matlab.ui.container.Panel
        GridLayout9_2                 matlab.ui.container.GridLayout
        YawSlider                     matlab.ui.control.Slider
        YawSliderLabel                matlab.ui.control.Label
        RollSlider                    matlab.ui.control.Slider
        RollSliderLabel               matlab.ui.control.Label
        PitchSlider                   matlab.ui.control.Slider
        PitchSliderLabel              matlab.ui.control.Label
        PhaseShiftPanel               matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        BzSlider                      matlab.ui.control.Slider
        BzSliderLabel                 matlab.ui.control.Label
        BySlider                      matlab.ui.control.Slider
        BySliderLabel                 matlab.ui.control.Label
        BxSlider                      matlab.ui.control.Slider
        BxSliderLabel                 matlab.ui.control.Label
        OptionsToolsTab               matlab.ui.container.Tab
        GridLayout8                   matlab.ui.container.GridLayout
        ExportDataPanel               matlab.ui.container.Panel
        GridLayout10_3                matlab.ui.container.GridLayout
        SavevisualisationfigButton    matlab.ui.control.Button
        SavesurfacemeshstlButton      matlab.ui.control.Button
        ExportmetrichistorycsvButton  matlab.ui.control.Button
        ImportDataPanel               matlab.ui.container.Panel
        GridLayout10_2                matlab.ui.container.GridLayout
        VoxelSizemmEditField          matlab.ui.control.NumericEditField
        VoxelSizemmLabel              matlab.ui.control.Label
        LoadsurfacemeshstlButton      matlab.ui.control.Button
        CalculationoptionsmayslowperformancePanel  matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        StoremetrichistoryCheckBox    matlab.ui.control.CheckBox
        MechanicalHomogenisationCheckBox  matlab.ui.control.CheckBox
        CurvatureMethodDropDown       matlab.ui.control.DropDown
        CurvatureMethodDropDownLabel  matlab.ui.control.Label
        TPMSDesignerGUIv22Label       matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        HideMeshCheckBox              matlab.ui.control.CheckBox
        Slider                        matlab.ui.control.Slider
        FaceProperty2Label            matlab.ui.control.Label
        P2                            matlab.ui.control.DropDown
        P1                            matlab.ui.control.DropDown
        FacePropertyLabel             matlab.ui.control.Label
        type                          matlab.ui.control.DropDown
        typeText                      matlab.ui.control.Label
        ax                            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        myTPMS TPMS; %Single unit cell
        cellhistory; %Array/List of unit cells
        cellID = 1; %ID of current cell
        stlmesh; %Inported Mesh
        opts; %General options
        savedView = 3; %Store 3d view data
    end
    
    methods (Access = private)
        function updateCellFigs(app)
            %Update all Cell Figures
            cla(app.ax);  opt = app.opts;

            switch opt.figureType
                case 'metric history'
                    data = app.cellhistory;
                    x = data.(app.P1.Value);
                    y = data.(app.P2.Value);
                    scatter(app.ax,x,y,'red',"filled");
                    xlabel(app.ax,app.P1.Value);ylabel(app.ax,app.P2.Value)
                    
                case 'field viewer'
                    pID = convertStringsToChars(extractBefore(app.P1.Value+" ", " "));
                    F = app.myTPMS.F;

                    if isfield(F.property,pID)
                        cData1 = F.property.(pID);
                    else
                        cData1 = zeros(size(F.property.X));

                    end

                    % Sample Points
                    c = colorbar(app.ax); 
                    h1 = patch(app.ax,'Faces',app.myTPMS.FV.faces,'Vertices',app.myTPMS.FV.vertices,'FaceVertexCData',[0 0 0.25],'FaceAlpha',0.5,'LineStyle','none'); hold(app.ax,'on');
                    [xq, yq, zq] = meshgrid(F.xq,F.yq,app.Slider.Value/100*F.upper(3));
                    xyslice = interp3(F.property.X,F.property.Y,F.property.Z,cData1,xq,yq,zq);
                    surf(app.ax,xq,yq,zq,xyslice,'LineStyle',opt.meshDisp);
                    L = max(min(cData1,[],'all',"omitnan"),-200);
                    U = min(max(cData1,[],'all',"omitnan"),200);
                    c.Label.String = 'Field Value'; colormap(app.ax,"jet");
                    caxis(app.ax,'manual'); caxis(app.ax,[L U]); 
                    if app.HideMeshCheckBox.Value
                        h1.FaceAlpha = 0;
                    else
                        h1.FaceAlpha = 0.2;
                    end
                        
                otherwise
                    app.myTPMS.plot(app.ax,opt.figureType,opt.currentProperty,opt.currentProperty2,opt);
            end
        end
        
        function runCurrent(app,source)
            tic
            updateStatus(app,10,"Running...",1);

            % Fundamental Properties/Parameters 
            equation = string(app.DesignDropDown.Value);
            tpmstype = string(app.TypeDropDown.Value);

            switch tpmstype
                case "double"
                    v = app.Isovaluesv1EditField.Value;
                    vf = app.v2EditField.Value;
                case "single"
                    vf = app.Isovaluesv1EditField.Value;
                    v = [];
                otherwise
                    vf = app.VolumeFractionSlider.Value;
                    v = [];
            end

            if strcmp(equation,'Custom')
                equation = app.EquationText.Value{1};
            end

            res = app.SeedNodesSlider.Value;
            cellSize = [app.LxSlider.Value app.LySlider.Value app.LzSlider.Value];
            ncells = [1 1 1];
            Rxyz = [app.PitchSlider.Value app.RollSlider.Value app.YawSlider.Value];
            offset = [app.BxSlider.Value app.BySlider.Value app.BzSlider.Value];
            app.myTPMS = TPMS(equation,tpmstype,v,vf,res,cellSize,ncells,Rxyz,offset);

            % Deal with Object/custom inport
            if contains(equation,".stl")
                    % Apply transformations now
                    app.StoremetrichistoryCheckBox.Value = false;
                    app.myTPMS.FV = app.stlmesh;
                    app.myTPMS.F = app.myTPMS.FV.getField(app.VoxelSizemmEditField.Value);
                    app.myTPMS.res = app.myTPMS.F.res;
                    app.myTPMS.cellSize = app.myTPMS.F.upper-app.myTPMS.F.lower;
            end

            
            if strcmp(source,'newcell')||strcmp(source,'changedOpts')
                updateStatus(app,50,"Calculating Properties...",1);
                app.myTPMS = app.myTPMS.properties(1,app.MechanicalHomogenisationCheckBox.Value,1,app.CurvatureMethodDropDown.Value);
            end

            app.EquationText.Value = char(app.myTPMS.u);
            app.Isovaluesv1EditField.Value = app.myTPMS.v;
            app.v2EditField.Value = app.myTPMS.vf;
            updateStatus(app,70,"Making Figure",1);
            updateCellFigs(app);

            if app.StoremetrichistoryCheckBox.Value
                updateStatus(app,90,"Saving metrics",1);
                app.cellhistory = [app.cellhistory; struct2table(app.myTPMS.export,'AsArray',true)];
            end

            txt = "Ready, last operation took: "+string(toc)+" s";
            updateStatus(app,100,txt,0);
        end
        
        
        function updateStatus(app,percent,text,icon)
                app.ProcessDataButton.Text = text;
            if icon
                app.ProcessDataButton.IconAlignment = 'bottom';
                wbar = permute(repmat(app.ProcessDataButton.BackgroundColor,15,1,200),[1,3,2]);
                % Black frame around waitbar
                wbar([1,end],:,:) = 0;
                wbar(:,[1,end],:) = 0;
                app.ProcessDataButton.Icon = wbar;
                % Update image data (royalblue)
                currentProg = min(round((size(wbar,2)-2)*(percent/100)),size(wbar,2)-2);
                app.ProcessDataButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.ProcessDataButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.ProcessDataButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
            else
                % remove waitbar
                app.ProcessDataButton.Icon = '';
            end
            drawnow();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define default options
            app.opts.curvature = 'patch';
            app.opts.mechanical = 0;
            app.opts.figureType = 'surfaceMesh';
            app.opts.isDoe = false;
            app.opts.meshDisp = '-';
            app.opts.currentProperty2 = app.P2.Value;
            app.opts.currentProperty = app.P1.Value;
            app.opts.currentMetric2 = "volume";
            app.opts.currentMetric1 = "ID";
            app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
            axis(app.ax,'vis3d','equal','tight');
            runCurrent(app,'newcell');
            app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {697, 697};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {382, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SavesurfacemeshstlButton
        function exportMesh(app, event)
            [file,path] = uiputfile('*.stl');
            if ~isempty(app.myTPMS.FV.facesC)
                F = [app.myTPMS.FV.faces; app.myTPMS.FV.facesC+length(app.myTPMS.FV.vertices)];
                V = [app.myTPMS.FV.vertices; app.myTPMS.FV.verticesC];
            elseif ~isempty(app.myTPMS.FV.faces)
                F = app.myTPMS.FV.faces;
                V = app.myTPMS.FV.vertices;
            else
                updateStatus(app,0,'Please Generate a Mesh Before Saving',0);
                return;
            end
            TR = triangulation(F,V);
            stlwrite(TR,string(fullfile(path,file)));
        end

        % Button pushed function: SavevisualisationfigButton
        function exportFig(app, event)
            %Copy axes to new figure and save
            [file,path] = uiputfile('*.fig');
            tempfig = figure(); % New figure
            savedFig = gca; % New Figure Axis Handle
            % Copy children.
            copyobj(app.ax.Children, savedFig);
            % Transfer other properties.
            uiAxParams = get(app.ax);
            uiAxParamNames = fieldnames(uiAxParams);
            % Get list of editable params in new axis
            editableParams = fieldnames(set(app.ax));
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis'; 'Title'; 'Position';...
                'Clipping'; 'Layout'; 'BoxStyle';'InnerPosition';'OuterPosition';'PositionConstraint'];
            uiAxGoodParams = rmfield(uiAxParams,badFields);
            % set editable params on new axes
            set(savedFig, uiAxGoodParams)
            % Add Colorbar
            savefig(tempfig,string(fullfile(path,file)));
        end

        % Button pushed function: ExportmetrichistorycsvButton
        function exportMetrics(app, event)
            [file,path] = uiputfile('*.csv');
            writetable(app.cellhistory,fullfile(path,file))
        end

        % Button pushed function: LoadsurfacemeshstlButton
        function importSTL(app, event)
            updateStatus(app,0,'Please select a valid .stl file',0);
            [file,path] = uigetfile('*.stl');
            updateStatus(app,0,'Importing .stl file...',1);
            app.stlmesh = surfaceMesh('stl',string(fullfile(path,file))); % Create the surfaceMesh object from the .stl file
            app.EquationText.Value = file;
            app.DesignDropDown.Items = [app.DesignDropDown.Items(1:8) {file}];
            runCurrent(app,'newcell');
        end

        % Value changed function: MechanicalHomogenisationCheckBox, P1, P2
        function changeOpts(app, event)
            switch event.Source
                case app.P1 % Change Property 1
                    app.opts.currentProperty = app.P1.Value;
                    updateCellFigs(app);
                case app.P2 % Change Property 2
                    app.opts.currentProperty2 = app.P2.Value;
                    updateCellFigs(app);
                case app.MechanicalHomogenisationCheckBox
                    if app.MechanicalHomogenisationCheckBox.Value
                        app.type.Items = [app.type.Items {'tensor'}];
                    else
                        app.type.Items = app.type.Items(1:6);
                    end
                    runCurrent(app,'newcell');
                case app.Slider
                    updateCellFigs(app);
            end
        end

        % Value changed function: type
        function changeVisulisation(app, event)
            try
            switch app.opts.figureType
                case "surfaceMesh"
                    app.savedView = get(app.ax,"view"); % Save view
                case "polemap"
                    app.savedView = get(app.ax,"view"); % Save view
                case "field viewer"
                    app.savedView = get(app.ax,"view"); % Save view
                case "tensor"
                    app.savedView = get(app.ax,"view"); % Save view
            end
            
            app.opts.figureType = event.Source.Value;
            cla(app.ax,'reset'); % Hard Clear Figures and viewport options
           
            
            switch app.opts.figureType
                case "surfaceMesh"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Property"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
                    app.Slider.Visible = false; app.HideMeshCheckBox.Visible = false;
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)];
                    app.P1.Value = app.P1.Items(1);
                case "polemap"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = ''; app.P1.Visible = false;
                    app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
                    app.Slider.Visible = false;
                case "tensor"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = ''; app.P1.Visible = false;
                    app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
                    app.Slider.Visible = false; app.HideMeshCheckBox.Visible = false;
                case "histogram"
                    app.FacePropertyLabel.Text = "Property"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = ''; app.P2.Visible = false;
                    app.Slider.Visible = false; app.HideMeshCheckBox.Visible = false;
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)];
                case "histogram2"
                    app.FacePropertyLabel.Text = "Face Property"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = "Face Property 2"; app.P2.Visible = true;
                    app.Slider.Visible = false; app.HideMeshCheckBox.Visible = false;
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)]; app.P1.Value = app.P1.Items(1);
                    app.P2.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty)]; app.P2.Value = app.P2.Items(2);
                case "metric history"
                    app.FacePropertyLabel.Text = "Cell Property"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = "Cell Property 2"; app.P2.Visible = true;
                    app.Slider.Visible = false; app.HideMeshCheckBox.Visible = false;
                    app.P1.Items = fieldnames(app.myTPMS.M); app.P1.Value = app.P1.Items(1);
                    app.P2.Items = fieldnames(app.myTPMS.M); app.P2.Value = app.P2.Items(2);
                case "field viewer"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Field Name"; app.P1.Visible = true;
                    app.FaceProperty2Label.Text = "Z-Slice"; app.P2.Visible = false;
                    app.Slider.Visible = true; app.HideMeshCheckBox.Visible = true;
                    app.P1.Items = fieldnames(app.myTPMS.F.property); app.P1.Value = app.P1.Items(1);
                otherwise
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.P1.Items = fieldnames(app.myTPMS.F.property); app.P1.Value = app.P1.Items(1);
            end
            updateCellFigs(app);
            catch
                updateStatus(app,100,'Could not generate figures, please select another option',0);
            end
        end

        % Callback function: BxSlider, BySlider, BzSlider, 
        % CurvatureMethodDropDown, DesignDropDown, EquationText, 
        % Isovaluesv1EditField, LxSlider, LySlider, LzSlider, PitchSlider, 
        % ProcessDataButton, RollSlider, SeedNodesSlider, TypeDropDown, 
        % VolumeFractionSlider, YawSlider, ntext, v2EditField, vftext
        function changeCell(app, event)
            % Deal with two input options
            switch event.Source
                case app.ntext
                    app.SeedNodesSlider.Value = app.ntext.Value;
                case app.vftext
                    app.VolumeFractionSlider.Value = app.vftext.Value;
                case app.SeedNodesSlider
                    app.ntext.Value = app.SeedNodesSlider.Value;
                case app.VolumeFractionSlider
                    app.vftext.Value = app.VolumeFractionSlider.Value;
                case app.TypeDropDown
                    switch app.TypeDropDown.Value
                        case "double"
                            app.VolumeFractionSlider.Visible = 0;
                            app.VolumeFractionSliderLabel.Visible = 0;
                            app.vftext.Visible = 0;
                            app.Isovaluesv1EditField.Editable = 1;
                            app.v2EditField.Visible = 1;
                            app.v2EditFieldLabel.Visible = 1;
                        case "single"
                            app.VolumeFractionSlider.Visible = 0;
                            app.VolumeFractionSliderLabel.Visible = 0;
                            app.vftext.Visible = 0;
                            app.Isovaluesv1EditField.Editable = 1;
                            app.v2EditField.Visible = 0;
                            app.v2EditFieldLabel.Visible = 0;
                        otherwise
                            app.VolumeFractionSlider.Visible = 1;
                            app.VolumeFractionSliderLabel.Visible = 1;
                            app.vftext.Visible = 1;
                            app.Isovaluesv1EditField.Editable = 0;
                            app.v2EditField.Visible = 0;
                            app.v2EditFieldLabel.Visible = 0;
                    end
                case app.EquationText
                    app.DesignDropDown.Value = "Custom";
            end
            
            %Update the model/analysis
            runCurrent(app,'newcell');
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            znew = event.Value;
            F = app.myTPMS.F;
            [xq, yq, zq] = meshgrid(F.xq,F.yq,znew/100*F.upper(3));
            pID = convertStringsToChars(extractBefore(app.P1.Value+" ", " "));
            if isfield(F.property,pID)
                cData = F.property.(pID);
            else
                cData = F.(pID);
            end
            app.ax.Children(1).CData = interp3(F.property.X,F.property.Y,F.property.Z,cData,xq,yq,zq);
            app.ax.Children(1).ZData = zq;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.LxSlider.Value = 10; app.LySlider.Value = 10; app.LzSlider.Value = 10;
            app.BxSlider.Value = 0; app.BySlider.Value = 0; app.BzSlider.Value = 0;
            app.PitchSlider.Value = 0;app.RollSlider.Value = 0; app.YawSlider.Value = 0;
            app.SeedNodesSlider.Value = 20; app.TypeDropDown.Value = 'network';
            app.DesignDropDown.Value = 'Gyroid'; app.VolumeFractionSlider.Value = 0.25;
            runCurrent(app,'newcell')
        end

        % Value changed function: HideMeshCheckBox
        function HideMeshCheckBoxValueChanged(app, event)
            updateCellFigs(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.TPMSDesignerGUIv22Label = uilabel(app.GridLayout4);
            app.TPMSDesignerGUIv22Label.Text = 'TPMS Designer GUI - v2.2';
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GridLayout5 = uigridlayout(app.GeneralTab);
            app.DesignDropDown = uidropdown(app.GridLayout5);
            app.DesignDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
            app.DesignDropDown.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.DesignDropDown.Tooltip = {'Select the equation you wish to generate.The most common TPMS (Gyroid, Diamond, Primitive) have been included along with some sample implicit objects (Sinusoidal, Sphere, PnormCube, Taurus).'; ''; '"Custom" to modify or write a new equation.  '; 'All entries after Custom will be imported structures (.stl or lattices)'};
            app.DesignDropDown.Value = 'Gyroid';
            app.DesignDropDownLabel = uilabel(app.GridLayout5);
            app.DesignDropDownLabel.Tooltip = {''};
            app.DesignDropDownLabel.Text = 'Design';
            app.EquationText = uitextarea(app.GridLayout5);
            app.EquationText.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.EquationText.Tooltip = {'Enter your custom function here'};
            app.EquationText.Value = {'Enter your custom equation here'};
            app.TypeDropDown = uidropdown(app.GridLayout5);
            app.TypeDropDown.Items = {'network', 'surface', 'single', 'double'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.TypeDropDown.Tooltip = {'Select the type of TPMS you wish to generate.'; ''; 'network: Produces a network structure using Volume fraction to approximate the isovalue v1. '; '(U≤v1)'; ''; 'surface: Produces a a surface with the midplane on the zero isosurface at target volume fraction. Effectively two results at Isovalues +v1 and -v1.'; '(-v1≤U≤v1)'; ''; 'single: Similar to network, but procudes a result directly from the isovalue'; ''; 'double: General form which directly finds the volume bounded by the two isovalues v1 and v2,'; '(U-v1)(U-v2)≤0'};
            app.TypeDropDown.Value = 'network';
            app.TypeDropDownLabel = uilabel(app.GridLayout5);
            app.TypeDropDownLabel.Tooltip = {''};
            app.TypeDropDownLabel.Text = 'Type';
            app.ntext = uieditfield(app.GridLayout5, 'numeric');
            app.ntext.Limits = [2 128];
            app.ntext.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.ntext.Value = 20;
            app.SeedNodesSliderLabel = uilabel(app.GridLayout5);
            app.SeedNodesSliderLabel.Text = 'Seed Nodes';
            app.SeedNodesSlider = uislider(app.GridLayout5);
            app.SeedNodesSlider.Limits = [2 128];
            app.SeedNodesSlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.SeedNodesSlider.Tooltip = {'Number of nodes per unit cell (in x,y,z directions)'};
            app.SeedNodesSlider.Value = 20;
            app.vftext = uieditfield(app.GridLayout5, 'numeric');
            app.vftext.Limits = [0 1];
            app.vftext.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.vftext.Value = 0.25;
            app.VolumeFractionSlider = uislider(app.GridLayout5);
            app.VolumeFractionSlider.Limits = [0 1];
            app.VolumeFractionSlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.VolumeFractionSlider.Value = 0.25;
            app.VolumeFractionSliderLabel = uilabel(app.GridLayout5);
            app.VolumeFractionSliderLabel.Text = 'Volume Fraction';
            app.Isovaluesv1Label = uilabel(app.GridLayout5);
            app.Isovaluesv1Label.Text = 'Isovalues      v1';
            app.Isovaluesv1EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Isovaluesv1EditField.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.Isovaluesv1EditField.Editable = 'off';
            app.Isovaluesv1EditField.Tooltip = {'Actual Isovalue Used'};
            app.v2EditFieldLabel = uilabel(app.GridLayout5);
            app.v2EditFieldLabel.Visible = 'off';
            app.v2EditFieldLabel.Text = 'v2';
            app.v2EditField = uieditfield(app.GridLayout5, 'numeric');
            app.v2EditField.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.v2EditField.Visible = 'off';
            app.v2EditField.Tooltip = {'Actual Secondary Isovalue used'};
            app.TransformationsTab = uitab(app.TabGroup);
            app.TransformationsTab.Title = 'Transformations';
            app.GridLayout6 = uigridlayout(app.TransformationsTab);
            app.PhaseShiftPanel = uipanel(app.GridLayout6);
            app.PhaseShiftPanel.Title = 'Phase Shift';
            app.GridLayout9 = uigridlayout(app.PhaseShiftPanel);
            app.BxSliderLabel = uilabel(app.GridLayout9);
            app.BxSliderLabel.Text = 'Bx';
            app.BxSlider = uislider(app.GridLayout9);
            app.BxSlider.Limits = [0 6.28318530717959];
            app.BxSlider.MajorTicks = [0 1.5707963267949 3.14159265358979 4.71238898038469 6.28318530717959];
            app.BxSlider.MajorTickLabels = {'0', 'pi/2', 'pi', '3pi/2', '2pi'};
            app.BxSlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.BySliderLabel = uilabel(app.GridLayout9);
            app.BySliderLabel.Text = 'By';
            app.BySlider = uislider(app.GridLayout9);
            app.BySlider.Limits = [0 6.28318530717959];
            app.BySlider.MajorTicks = [0 1.5707963267949 3.14159265358979 4.71238898038469 6.28318530717959];
            app.BySlider.MajorTickLabels = {'0', 'pi/2', 'pi', '3pi/2', '2pi'};
            app.BySlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.BzSliderLabel = uilabel(app.GridLayout9);
            app.BzSliderLabel.Text = 'Bz';
            app.BzSlider = uislider(app.GridLayout9);
            app.BzSlider.Limits = [0 6.28318530717959];
            app.BzSlider.MajorTicks = [0 1.5707963267949 3.14159265358979 4.71238898038469 6.28318530717959];
            app.BzSlider.MajorTickLabels = {'0', 'pi/2', 'pi', '3pi/2', '2pi'};
            app.BzSlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.RotationsdegreesPanel = uipanel(app.GridLayout6);
            app.RotationsdegreesPanel.Title = 'Rotations (degrees)';
            app.GridLayout9_2 = uigridlayout(app.RotationsdegreesPanel);
            app.PitchSliderLabel = uilabel(app.GridLayout9_2);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.GridLayout9_2);
            app.PitchSlider.Limits = [0 180];
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.RollSliderLabel = uilabel(app.GridLayout9_2);
            app.RollSliderLabel.Text = 'Roll';
            app.RollSlider = uislider(app.GridLayout9_2);
            app.RollSlider.Limits = [0 90];
            app.RollSlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.YawSliderLabel = uilabel(app.GridLayout9_2);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.GridLayout9_2);
            app.YawSlider.Limits = [0 180];
            app.YawSlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.CellSizemmPanel = uipanel(app.GridLayout6);
            app.CellSizemmPanel.Title = 'Cell Size (mm)';
            app.GridLayout9_3 = uigridlayout(app.CellSizemmPanel);
            app.LxSliderLabel = uilabel(app.GridLayout9_3);
            app.LxSliderLabel.Text = 'Lx';
            app.LxSlider = uislider(app.GridLayout9_3);
            app.LxSlider.Limits = [0 20];
            app.LxSlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.LxSlider.Value = 10;
            app.LySliderLabel = uilabel(app.GridLayout9_3);
            app.LySliderLabel.Text = 'Ly';
            app.LySlider = uislider(app.GridLayout9_3);
            app.LySlider.Limits = [0 20];
            app.LySlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.LySlider.Value = 10;
            app.LzSliderLabel = uilabel(app.GridLayout9_3);
            app.LzSliderLabel.Text = 'Lz';
            app.LzSlider = uislider(app.GridLayout9_3);
            app.LzSlider.Limits = [0 20];
            app.LzSlider.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.LzSlider.Value = 10;
            app.OptionsToolsTab = uitab(app.TabGroup);
            app.OptionsToolsTab.Title = 'Options & Tools';
            app.GridLayout8 = uigridlayout(app.OptionsToolsTab);
            app.CalculationoptionsmayslowperformancePanel = uipanel(app.GridLayout8);
            app.CalculationoptionsmayslowperformancePanel.Title = 'Calculation options (may slow performance)';
            app.GridLayout10 = uigridlayout(app.CalculationoptionsmayslowperformancePanel);
            app.CurvatureMethodDropDownLabel = uilabel(app.GridLayout10);
            app.CurvatureMethodDropDownLabel.Text = 'Curvature Method';
            app.CurvatureMethodDropDown = uidropdown(app.GridLayout10);
            app.CurvatureMethodDropDown.Items = {'none', 'patch', 'trimesh', 'implicit', 'imgradient'};
            app.CurvatureMethodDropDown.DropDownOpeningFcn = createCallbackFcn(app, @changeCell, true);
            app.CurvatureMethodDropDown.Value = 'none';
            app.MechanicalHomogenisationCheckBox = uicheckbox(app.GridLayout10);
            app.MechanicalHomogenisationCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeOpts, true);
            app.MechanicalHomogenisationCheckBox.Text = 'Mechanical Homogenisation';
            app.StoremetrichistoryCheckBox = uicheckbox(app.GridLayout10);
            app.StoremetrichistoryCheckBox.Text = 'Store metric history';
            app.StoremetrichistoryCheckBox.Value = true;
            app.ImportDataPanel = uipanel(app.GridLayout8);
            app.ImportDataPanel.Title = 'Import Data';
            app.GridLayout10_2 = uigridlayout(app.ImportDataPanel);
            app.LoadsurfacemeshstlButton = uibutton(app.GridLayout10_2, 'push');
            app.LoadsurfacemeshstlButton.ButtonPushedFcn = createCallbackFcn(app, @importSTL, true);
            app.LoadsurfacemeshstlButton.Text = 'Load surface mesh (.stl)';
            app.VoxelSizemmLabel = uilabel(app.GridLayout10_2);
            app.VoxelSizemmLabel.Text = {'Voxel Size'; '(mm)'};
            app.VoxelSizemmEditField = uieditfield(app.GridLayout10_2, 'numeric');
            app.VoxelSizemmEditField.Limits = [0 Inf];
            app.VoxelSizemmEditField.Value = 1;
            app.ExportDataPanel = uipanel(app.GridLayout8);
            app.ExportDataPanel.Title = 'Export Data';
            app.GridLayout10_3 = uigridlayout(app.ExportDataPanel);
            app.ExportmetrichistorycsvButton = uibutton(app.GridLayout10_3, 'push');
            app.ExportmetrichistorycsvButton.ButtonPushedFcn = createCallbackFcn(app, @exportMetrics, true);
            app.ExportmetrichistorycsvButton.Text = 'Export metric history (.csv)';
            app.SavesurfacemeshstlButton = uibutton(app.GridLayout10_3, 'push');
            app.SavesurfacemeshstlButton.ButtonPushedFcn = createCallbackFcn(app, @exportMesh, true);
            app.SavesurfacemeshstlButton.Text = 'Save surface mesh (.stl)';
            app.SavevisualisationfigButton = uibutton(app.GridLayout10_3, 'push');
            app.SavevisualisationfigButton.ButtonPushedFcn = createCallbackFcn(app, @exportFig, true);
            app.SavevisualisationfigButton.Text = 'Save visualisation (.fig)';
            app.ProcessDataButton = uibutton(app.GridLayout4, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @changeCell, true);
            app.ProcessDataButton.Text = 'Initialising...';
            app.ResetButton = uibutton(app.GridLayout4, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GridLayout2.Tooltip = {''};
            app.ax = uiaxes(app.GridLayout2);
            xlabel(app.ax, 'X')
            ylabel(app.ax, 'Y')
            zlabel(app.ax, 'Z')
            app.typeText = uilabel(app.GridLayout2);
            app.typeText.Text = 'Visualisation Type';
            app.type = uidropdown(app.GridLayout2);
            app.type.Items = {'surfaceMesh', 'histogram', 'histogram2', 'polemap', 'field viewer', 'metric history'};
            app.type.ValueChangedFcn = createCallbackFcn(app, @changeVisulisation, true);
            app.type.Value = 'surfaceMesh';
            app.FacePropertyLabel = uilabel(app.GridLayout2);
            app.FacePropertyLabel.Text = 'Property';
            app.P1 = uidropdown(app.GridLayout2);
            app.P1.Items = {'inclination'};
            app.P1.ValueChangedFcn = createCallbackFcn(app, @changeOpts, true);
            app.P1.Value = 'inclination';
            app.P2 = uidropdown(app.GridLayout2);
            app.P2.Items = {'Nz'};
            app.P2.ValueChangedFcn = createCallbackFcn(app, @changeOpts, true);
            app.P2.Visible = 'off';
            app.P2.Value = 'Nz';
            app.FaceProperty2Label = uilabel(app.GridLayout2);
            app.FaceProperty2Label.Visible = 'off';
            app.FaceProperty2Label.Text = 'Property 2';
            app.Slider = uislider(app.GridLayout2);
            app.Slider.MajorTicks = [0 50 100];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Visible = 'off';
            app.HideMeshCheckBox = uicheckbox(app.GridLayout2);
            app.HideMeshCheckBox.ValueChangedFcn = createCallbackFcn(app, @HideMeshCheckBoxValueChanged, true);
            app.HideMeshCheckBox.Visible = 'off';
            app.HideMeshCheckBox.Text = 'Hide Mesh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TPMS_Designer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end