classdef TPMS_Designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TPMSDesignerGUIUIFigure       matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        SurfaceMeshstlMenu_2          matlab.ui.container.Menu
        VoxelMeshinpMenu              matlab.ui.container.Menu
        MetricHistorycsvMenu          matlab.ui.container.Menu
        VisualOptionsMenu             matlab.ui.container.Menu
        CAxisTypeMenu                 matlab.ui.container.Menu
        linearMenu                    matlab.ui.container.Menu
        logMenu                       matlab.ui.container.Menu
        CloseAllFiguresMenu           matlab.ui.container.Menu
        HistogramMenu                 matlab.ui.container.Menu
        camcontrols                   matlab.ui.container.Menu
        CalculationOptionsMenu        matlab.ui.container.Menu
        mechanicalMenu                matlab.ui.container.Menu
        CurvatureMethodMenu           matlab.ui.container.Menu
        noneMenu                      matlab.ui.container.Menu
        KroonMenu                     matlab.ui.container.Menu
        trimesh2Menu                  matlab.ui.container.Menu
        meyer2003Menu                 matlab.ui.container.Menu
        implicitMenu                  matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLeft                      matlab.ui.container.GridLayout
        GridUpdate                    matlab.ui.container.GridLayout
        AutoUpdateSwitch              matlab.ui.control.Switch
        ResetButton                   matlab.ui.control.Button
        ProcessDataButton             matlab.ui.control.Button
        AutoUpdateSwitchLabel         matlab.ui.control.Label
        GridScrollable                matlab.ui.container.GridLayout
        GeneralEquationPanel          matlab.ui.container.Panel
        GridGeneral                   matlab.ui.container.GridLayout
        volumeSlider                  matlab.ui.control.Slider
        EquationText                  matlab.ui.control.TextArea
        vol                           matlab.ui.control.NumericEditField
        volLabel                      matlab.ui.control.Label
        v2                            matlab.ui.control.NumericEditField
        v2Label                       matlab.ui.control.Label
        v1                            matlab.ui.control.NumericEditField
        v1Label                       matlab.ui.control.Label
        EquationDropDown              matlab.ui.control.DropDown
        EquationDropDownLabel         matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        TransformationsPanel          matlab.ui.container.Panel
        GridTransformations           matlab.ui.container.GridLayout
        UnitCellSizeLabel             matlab.ui.control.Label
        lx                            matlab.ui.control.NumericEditField
        ly                            matlab.ui.control.NumericEditField
        lz                            matlab.ui.control.NumericEditField
        linkl                         matlab.ui.control.StateButton
        PhaseShiftLabel               matlab.ui.control.Label
        bx                            matlab.ui.control.NumericEditField
        by                            matlab.ui.control.NumericEditField
        bz                            matlab.ui.control.NumericEditField
        RotationsdegLabel             matlab.ui.control.Label
        rz                            matlab.ui.control.NumericEditField
        ry                            matlab.ui.control.NumericEditField
        rx                            matlab.ui.control.NumericEditField
        DesignSizeandResolutionPanel  matlab.ui.container.Panel
        GridDesignSizeandResolution   matlab.ui.container.GridLayout
        SizeMethodDropDown            matlab.ui.control.DropDown
        NxyzLabel                     matlab.ui.control.Label
        nx                            matlab.ui.control.NumericEditField
        ny                            matlab.ui.control.NumericEditField
        nz                            matlab.ui.control.NumericEditField
        Lx                            matlab.ui.control.NumericEditField
        Lz                            matlab.ui.control.NumericEditField
        Ly                            matlab.ui.control.NumericEditField
        LoadstlButton                 matlab.ui.control.Button
        filename                      matlab.ui.control.Label
        res                           matlab.ui.control.NumericEditField
        voxSize                       matlab.ui.control.NumericEditField
        ResolutionvoxedSizeLabel      matlab.ui.control.Label
        ResolutionvoxedSizeLabel_2    matlab.ui.control.Label
        SizeMethodDropDownLabel       matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridRight                     matlab.ui.container.GridLayout
        GridFigureOptions1            matlab.ui.container.GridLayout
        P2                            matlab.ui.control.DropDown
        P1                            matlab.ui.control.DropDown
        type                          matlab.ui.control.DropDown
        FaceProperty2Label            matlab.ui.control.Label
        FacePropertyLabel             matlab.ui.control.Label
        typeText                      matlab.ui.control.Label
        GridFigureOptions2            matlab.ui.container.GridLayout
        ExportfigButton               matlab.ui.control.Button
        histogramN                    matlab.ui.control.Spinner
        sliderUI                      matlab.ui.control.Slider
        polemapN                      matlab.ui.control.Spinner
        polemapNLabel                 matlab.ui.control.Label
        sliderLabel                   matlab.ui.control.Label
        statusText                    matlab.ui.control.Label
        ax                            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        myTPMS UnitCell; %Single unit cell
        cellhistory; %Array/List of unit cells
        cellID = 1; %ID of current cell (starts at 1)
        stlmesh; %Imported Mesh
        opts; %General options
        savedView = 3; %Store 3d view data 
    end
    
    methods (Access = private)
        function updateFigure(app)
            %Update all Cell Figures using h = plot(UnitCell,plottype,property1,property2,opts,ax)
            cla(app.ax); 
            opt = app.opts;

            switch opt.figureType
                case 'metrics scatterplot'
                    x = app.cellhistory.(app.P1.Value);
                    y = app.cellhistory.(app.P2.Value);
                    scatter(app.ax,x,y,'red',"filled"); grid(app.ax,"on");
                    xlabel(app.ax,app.P1.Value);ylabel(app.ax,app.P2.Value)
                case 'field viewer'
                    pID = convertStringsToChars(extractBefore(app.P1.Value+" ", " "));
                    F = app.myTPMS.F;

                    if isfield(F.property,pID)
                        cData1 = F.property.(pID);
                    else
                        cData1 = zeros(size(F.property.X));
                    end

                    % Sample Points
                    c = colorbar(app.ax); 
                    patch(app.ax,'Faces',app.myTPMS.FV.faces,'Vertices',app.myTPMS.FV.vertices,'FaceVertexCData',[0 0 0.25],'FaceAlpha',0.2,'LineStyle','none'); hold(app.ax,'on');
                    [xq, yq, zq] = meshgrid(F.xq,F.yq,app.sliderUI.Value/100*(F.upper(3)-F.lower(3))+F.lower(3));
                    xyslice = interp3(F.property.X,F.property.Y,F.property.Z,cData1,xq,yq,zq);
                    surf(app.ax,xq,yq,zq,xyslice,'LineStyle',opt.meshDisp);
                    c.Label.String = app.P1.Value; colormap(app.ax,"jet");
                    [U, L] = bounds(cData1,'all','omitmissing');
                    clim(app.ax,[U,L]);
                case 'polemap'
                    opt.n = app.polemapN.Value;
                    app.myTPMS.plot(opt.figureType,app.P1.Value,app.P2.Value,opt,app.ax);
                otherwise
                    opt.n = app.histogramN.Value;
                    app.myTPMS.plot(opt.figureType,app.P1.Value,app.P2.Value,opt,app.ax);
            end
            set(app.ax,'ColorScale',app.opts.colorscale)
            drawnow();
        end
        
        function runCurrent(app)
            tic %Start the timer
            app.ProcessDataButton.BackgroundColor = 'Yellow';
            app.ProcessDataButton.Text = 'Updating Please Wait...';
            drawnow;

            % Fundamental Properties/Parameters 
            tpmstype = string(app.TypeDropDown.Value);
            equation = string(app.EquationDropDown.Value);
            if strcmp(equation,'Custom')
                equation = app.EquationText.Value{1};
            end

            A = [app.lx.Value 0 0; 0 app.ly.Value 0; 0 0 app.lz.Value];
            Rx = [1 0 0; 0 cosd(app.rx.Value) -sind(app.rx.Value); 0 sind(app.rx.Value) cosd(app.rx.Value)];
            Ry = [cosd(app.ry.Value) 0 sind(app.ry.Value); 0 1 0; -sind(app.ry.Value) 0 cosd(app.ry.Value)];
            Rz = [cosd(app.rz.Value) -sind(app.rz.Value) 0; sind(app.rz.Value) cosd(app.rz.Value) 0; 0 0 1];
            T = [app.bx.Value; app.by.Value; app.bz.Value];
            T = [Rz*Ry*Rx*A T; 0 0 0 1];
            if verLessThan('matlab', '9.13')
                tform = affine3d(T'); % Compatability for older version
            else
                tform = affinetform3d(T);
            end

            switch app.SizeMethodDropDown.Value
                case 'stl' % Not currently implemented
                    volume = bulkSize('FV',app.stlmesh);
                case 'box'
                    volume = bulkSize('box',[app.Lx.Value, app.Ly.Value, app.Lz.Value]);
                case 'cylinder'
                    volume = bulkSize('cylinder',[app.Lx.Value, app.Ly.Value, app.Lz.Value]);
                otherwise % 'Unit Cell Case'
                    volume = bulkSize('box',diag(A)'.*[app.nx.Value, app.ny.Value, app.nz.Value]);
            end

            app.myTPMS = UnitCell(tpmstype,equation,app.v1.Value,app.v2.Value,app.voxSize.Value,tform,volume);
            computeMesh = 1;
            app.myTPMS = app.myTPMS.compute(app.opts.curvature,app.mechanicalMenu.Checked,computeMesh);
            
            % Save the metric information
            if ~contains(equation,".stl")
                temp = [table(app.cellID,'VariableNames',{'ID'}) struct2table(app.myTPMS.export,'AsArray',true)];
                app.cellID = app.cellID+1;
                if isempty(app.cellhistory)
                    app.cellhistory = temp;
                else
                    app.cellhistory = vertcat(app.cellhistory,temp);
                end
            end

            if strcmp(app.myTPMS.type,'lattice')
                app.EquationText.Value = app.EquationDropDown.Value;
            else
                app.EquationText.Value = char(app.myTPMS.u);
            end


            app.vol.Value = app.myTPMS.M.relativeVolume;
            updateFigure(app);

            app.ProcessDataButton.BackgroundColor = 'Green';
            app.ProcessDataButton.Text = 'Update';
            txt = "Last operation successful after: "+string(toc)+"seconds";
            app.statusText.Text = txt;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define default options
            app.ax = app.ax;
            axis(app.ax,"equal","vis3d","tight");
            app.opts.curvature = 'none';
            app.opts.figureType = 'surfaceMesh';
            app.opts.meshDisp = '-';
            app.opts.colorscale = 'linear';
            app.opts.currentProperty2 = app.P2.Value;
            app.opts.currentProperty = app.P1.Value;
            app.opts.currentMetric2 = "volume";
            app.opts.currentMetric1 = "ID";
            app.FaceProperty2Label.Text = 'Quiver Plot';
            app.stlmesh = icosphere(2);
            app.stlmesh.vertices = app.stlmesh.vertices*30;
            runCurrent(app);
            app.P1.Items = [fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.FV.Fproperty)];
            app.P2.Items = ['none'; fieldnames(app.myTPMS.FV.Qproperty)];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TPMSDesignerGUIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {632, 632};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {362, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SurfaceMeshstlMenu_2
        function exportSTL(app, event)
            [file,path] = uiputfile('*.stl');
            app.myTPMS.FV.exportSTL(fullfile(path,file));
        end

        % Menu selected function: VoxelMeshinpMenu
        function exportINP(app, event)
            [file,path] = uiputfile('*.inp');
            app.myTPMS.F.exportINP(fullfile(path,file));
        end

        % Button pushed function: ExportfigButton
        function exportFig(app, event)
            %Copy axes to new figure and save
            figure('Name','TPMS Designer'); % New figure
            tempax = gca; % New Figure Axis Handle
            % Copy children.
            copyobj(app.ax.Children, tempax);
            % Transfer other properties.
            uiAxParams = get(app.ax);
            uiAxParamNames = fieldnames(uiAxParams);
            % Get list of editable params in new axis
            editableParams = fieldnames(set(app.ax));
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis'; 'Title'; 'Position';...
                'Clipping'; 'Layout';'InnerPosition';'OuterPosition'];
            uiAxGoodParams = rmfield(uiAxParams,badFields);
            
            % Set editable params on new axes
            set(tempax, uiAxGoodParams);
            c= colorbar(tempax); title(tempax,app.type.Value);
            c.Label.String = app.P1.Value;
        end

        % Menu selected function: MetricHistorycsvMenu
        function exportMetrics(app, event)
            [file,path] = uiputfile('*.csv');
            writetable(app.cellhistory,fullfile(path,file))
        end

        % Button pushed function: LoadstlButton
        function importSTL(app, event)
            [file,path] = uigetfile('*.stl');
            app.stlmesh = SurfMesh('stl',string(fullfile(path,file))); % Create the surfaceMesh object from the .stl file
            app.filename.Text = file;
            tempbbox = range(app.stlmesh.vertices,1);
            app.Lx.Value = tempbbox(1); app.Ly.Value = tempbbox(2); app.Lz.Value = tempbbox(3);
            app.voxSize.Value = max(tempbbox,[],'all')/app.res.Value;
        end

        % Callback function: KroonMenu, P1, P2, histogramN, implicitMenu, 
        % ...and 7 other components
        function changeOptions(app, event)

            if event.Source == app.P1
                    app.opts.currentProperty = app.P1.Value;
            elseif event.Source == app.P2 % Change Property 2
                    app.opts.currentProperty2 = app.P2.Value; 
            elseif event.Source == app.mechanicalMenu
                    app.mechanicalMenu.Checked = ~app.mechanicalMenu.Checked;
            elseif event.Source == app.linearMenu
                    app.linearMenu.Checked = 'on';
                    app.logMenu.Checked = 'off';
                    app.opts.colorscale = 'linear';
            elseif event.Source == app.logMenu
                    app.linearMenu.Checked = 'off';
                    app.logMenu.Checked = 'on';
                    app.opts.colorscale = 'log';
            elseif event.Source==app.noneMenu||event.Source==app.KroonMenu||event.Source==app.trimesh2Menu||...
                event.Source==app.meyer2003Menu||event.Source==app.implicitMenu % Change Curvature Case
                    app.noneMenu.Checked = 'off';
                    app.trimesh2Menu.Checked = 'off';
                    app.meyer2003Menu.Checked = 'off';
                    app.implicitMenu.Checked = 'off';
                    app.KroonMenu.Checked = 'off';
                    event.Source.Checked = 'on';
                    app.opts.curvature = event.Source.Text;
            end
            
            updateFigure(app);
        end

        % Value changed function: type
        function changeVisulisation(app, event)
            try
                % Clean up UI and save views ready for new figure type
            switch app.opts.figureType
                case "surfaceMesh"
                    app.savedView = get(app.ax,"view"); % Save view
                    app.P2.Visible = false; 
                    app.FaceProperty2Label.Text = '';
                case "polemap"
                    app.savedView = get(app.ax,"view"); % Save view
                    app.polemapN.Visible = false;
                    app.polemapNLabel.Visible = false;
                    app.P1.Visible = true;
                case "field viewer"
                    app.savedView = get(app.ax,"view"); % Save view
                    app.sliderUI.Visible = false; 
                    app.sliderLabel.Text = '';
                case "voxel"
                    app.savedView = get(app.ax,"view"); % Save view
                case "tensor"
                    app.savedView = get(app.ax,"view"); % Save view
                    app.P1.Visible = true;
                case "histogram"
                    app.histogramN.Visible = false;
                    app.polemapNLabel.Text = '';
                case "histogram2"
                    app.histogramN.Visible = false;
                    app.polemapNLabel.Text = '';
                    app.FaceProperty2Label.Text = ''; 
                    app.P2.Visible = false;
                case "metrics"
                    app.P2.Visible = false;
            end
            
            app.opts.figureType = event.Source.Value;
            cla(app.ax,'reset'); % Hard Clear Figures and viewport options
           
            switch app.opts.figureType
                case "surfaceMesh"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Property";
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.FV.Fproperty)];
                    app.P2.Items = ['none'; fieldnames(app.myTPMS.FV.Qproperty)]; app.FaceProperty2Label.Text = 'Property (Quiver plot)';
                case "polemap"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = ''; app.P1.Visible = false;
                    app.polemapN.Visible = true;
                case "tensor"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = ''; app.P1.Visible = false;
                case "histogram"
                    app.FacePropertyLabel.Text = "Property";
                    app.histogramN.Visible = true; app.polemapNLabel.Text = 'Number of Bins';
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.F.property)];
                case "histogram2"
                    app.FacePropertyLabel.Text = "Face Property";
                    app.FaceProperty2Label.Text = "Face Property 2"; app.P2.Visible = true;
                    app.histogramN.Visible = true; app.polemapNLabel.Text = 'Number of Bins';
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.FV.Fproperty)]; app.P1.Value = app.P1.Items(1);
                    app.P2.Items = [fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.FV.Fproperty)]; app.P2.Value = app.P2.Items(2);
                case "metrics scatterplot"
                    app.FacePropertyLabel.Text = "Cell Properties";
                    app.P2.Visible = true;
                    app.P1.Items = app.cellhistory.Properties.VariableNames; app.P1.Value = app.P1.Items(1);
                    app.P2.Items = app.cellhistory.Properties.VariableNames; app.P2.Value = app.P2.Items(2);
                case "field viewer"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Field Name"; app.P1.Visible = true; app.sliderUI.Visible = true; 
                    app.sliderLabel.Visible = true;
                    app.P1.Items = fieldnames(app.myTPMS.F.property);
                case "voxel"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Field Name";
                    app.P1.Items = fieldnames(app.myTPMS.F.property);
                otherwise
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.P1.Items = fieldnames(app.myTPMS.F.property); app.P1.Value = app.P1.Items(1);
            end
            updateFigure(app);
            catch
                app.statusText.Text = 'Failed to update - please try again';
            end
        end

        % Callback function: ProcessDataButton, TPMSDesignerGUIUIFigure
        function updateButton(app, event)
            % Deal with two input options
            runCurrent(app);
        end

        % Value changing function: sliderUI
        function slicesliderUIChanging(app, event)
            znew = event.Value;
            F = app.myTPMS.F;
            [xq, yq, zq] = meshgrid(F.xq,F.yq,znew/100*(F.upper(3)-F.lower(3))+F.lower(3));
            pID = convertStringsToChars(extractBefore(app.P1.Value+" ", " "));
            if isfield(F.property,pID)
                cData = F.property.(pID);
            else
                cData = F.(pID);
            end
            app.ax.Children(1).CData = interp3(F.property.X,F.property.Y,F.property.Z,cData,xq,yq,zq);
            app.ax.Children(1).ZData = zq;
        end

        % Button pushed function: ResetButton
        function resetButton(app, event)
            app.lx.Value = 30; app.ly.Value = 30; app.lz.Value = 30;
            app.sliderUI.Value = 10;
            app.bx.Value = 0; app.by.Value = 0; app.bz.Value = 0;
            app.nx.Value = 1; app.ny.Value = 1; app.nz.Value = 1;
            app.rx.Value = 0;app.ry.Value = 0; app.rz.Value = 0;
            app.voxSize.Value = 1.0;
            app.TypeDropDown.Value = 'network';
            app.EquationDropDown.Value = 'Gyroid'; 
            app.volumeSlider.Value = 0.25; app.vol.Value = 0.25;
            app.cmap.Value = 'linear'; app.histogramN.Value = 100;
            app.polemapN.Value = 3;
            app.cellhistory = []; app.cellID = 1;
            runCurrent(app);
        end

        % Callback function: EquationDropDown, EquationText, Lx, Ly, Lz, 
        % ...and 19 other components
        function changeCell(app, event)
            switch event.Source
                case app.linkl
                    if app.linkl.Value
                        app.ly.Value = app.lx.Value;
                        app.lz.Value = app.lx.Value;
                        app.ly.Enable = "off"; app.lz.Enable = "off";
                    else
                        app.ly.Enable = "on"; app.lz.Enable = "on";
                    end
                case app.lx
                    if app.linkl.Value
                        app.ly.Value = app.lx.Value;
                        app.lz.Value = app.lx.Value;
                    end
                case app.v1
                    if app.volumeSlider.Visible
                        app.volumeSlider.Value = event.Value;
                    end
                case app.volumeSlider
                    app.v1.Value = event.Value;
                case app.TypeDropDown
                    switch app.TypeDropDown.Value
                        case "double"
                            app.volumeSlider.Visible = 0;
                            app.v2.Visible = 1;
                            app.v1Label.Text = 'Isovalue v1:'; app.v2Label.Text = 'v2:';
                            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Lidinoid', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
                        case "lattice"
                            app.volumeSlider.Visible = 0;
                            app.v2.Visible = 1;
                            app.v1Label.Text = 'Strut Radius'; app.v2Label.Text = 'Node Radius';
                            app.EquationDropDown.Items = {'BCC', 'BCCXYZ','Cubic','FCCXYZ','Octet','OctetXYZ','tesseract','vintiles','x_cross_grid','Custom'};
                        case "surface"
                            app.volumeSlider.Visible = 0;
                            app.v2.Visible = 1;
                            app.v1Label.Text = 'Target Volume Fraction'; app.v2Label.Text = 'Midplane v2';
                            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Lidinoid', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
                        case "network"
                            app.volumeSlider.Visible = 1;
                            app.v2.Visible = 0;
                            app.v1Label.Text = 'Target Volume Fraction'; app.v2Label.Text = '';
                            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Lidinoid', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
                        otherwise % Single/default
                            app.volumeSlider.Visible = 0;
                            app.v2.Visible = 0;
                            app.v1Label.Text = 'Isovalue v1:'; app.v2Label.Text = '';
                            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Lidinoid', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
                    end
                case app.EquationText
                    app.EquationDropDown.Value = "Custom";
                case app.SizeMethodDropDown
                    switch app.SizeMethodDropDown.Value
                        case 'box'
                            app.nx.Visible = 0; app.ny.Visible = 0; app.nz.Visible = 0;
                            app.Lx.Visible = 1; app.Ly.Visible = 1; app.Lz.Visible = 1;
                            app.filename.Visible = 0; app.LoadstlButton.Visible = 0;
                            app.NxyzLabel.Text = 'Size (x,y,z):';
                        case 'stl'
                            app.nx.Visible = 0; app.ny.Visible = 0; app.nz.Visible = 0;
                            app.Lx.Visible = 1; app.Ly.Visible = 1; app.Lz.Visible = 1;
                            app.filename.Visible = 1; app.LoadstlButton.Visible = 1;
                            app.NxyzLabel.Text = 'Size (x,y,z):';
                        case 'cylinder'
                            app.nx.Visible = 0; app.ny.Visible = 0; app.nz.Visible = 0;
                            app.Lx.Visible = 1; app.Ly.Visible = 1; app.Lz.Visible = 1;
                            app.filename.Visible = 0; app.LoadstlButton.Visible = 0;
                            app.NxyzLabel.Text = 'Cylinder (dX,dY,Length):';
                        case 'unitcell'
                            app.nx.Visible = 1; app.ny.Visible = 1; app.nz.Visible = 1;
                            app.Lx.Visible = 0; app.Ly.Visible = 0; app.Lz.Visible = 0;
                            app.filename.Visible = 0; app.LoadstlButton.Visible = 0;
                            app.NxyzLabel.Text = 'Cells (nx,ny,nz)';
                    end
            end

            switch app.SizeMethodDropDown.Value
                case 'unitcell'
                    app.voxSize.Value = app.lx.Value*app.nx.Value/double(app.res.Value);
                otherwise
                    app.voxSize.Value = max([app.Lx.Value app.Ly.Value app.Lz.Value],[],'all')/double(app.res.Value);
            end

            
            if app.AutoUpdateSwitch.Value
                runCurrent(app);
            end
        end

        % Menu selected function: CloseAllFiguresMenu
        function CloseAllFiguresButtonPushed(app, event)
            close all;
        end

        % Menu selected function: camcontrols
        function camcontrolsSelected(app, event)
            if app.camcontrols.Checked 
                app.camcontrols.Checked = 0;
                cameratoolbar 'hide'
            else
                app.camcontrols.Checked = 1;
                cameratoolbar 'show'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TPMSDesignerGUIUIFigure = uifigure('Visible', 'off');
            colormap(app.TPMSDesignerGUIUIFigure, 'turbo');
            app.TPMSDesignerGUIUIFigure.Name = 'TPMS Designer GUI ';
            app.TPMSDesignerGUIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.TPMSDesignerGUIUIFigure.KeyPressFcn = createCallbackFcn(app, @updateButton, true);
            app.TPMSDesignerGUIUIFigure.Tag = ' v3.2.0';
            app.Menu = uimenu(app.TPMSDesignerGUIUIFigure);
            app.Menu.Text = 'Menu';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Text = 'Export';
            app.SurfaceMeshstlMenu_2 = uimenu(app.ExportMenu);
            app.SurfaceMeshstlMenu_2.MenuSelectedFcn = createCallbackFcn(app, @exportSTL, true);
            app.SurfaceMeshstlMenu_2.Text = 'Surface Mesh (.stl)';
            app.VoxelMeshinpMenu = uimenu(app.ExportMenu);
            app.VoxelMeshinpMenu.MenuSelectedFcn = createCallbackFcn(app, @exportINP, true);
            app.VoxelMeshinpMenu.Text = 'Voxel Mesh (.inp)';
            app.MetricHistorycsvMenu = uimenu(app.ExportMenu);
            app.MetricHistorycsvMenu.MenuSelectedFcn = createCallbackFcn(app, @exportMetrics, true);
            app.MetricHistorycsvMenu.Text = 'Metric History (.csv)';
            app.VisualOptionsMenu = uimenu(app.Menu);
            app.VisualOptionsMenu.Text = 'Visual Options';
            app.CAxisTypeMenu = uimenu(app.VisualOptionsMenu);
            app.CAxisTypeMenu.Text = 'CAxis Type';
            app.linearMenu = uimenu(app.CAxisTypeMenu);
            app.linearMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.linearMenu.Checked = 'on';
            app.linearMenu.Text = 'linear';
            app.logMenu = uimenu(app.CAxisTypeMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.logMenu.Text = 'log';
            app.CloseAllFiguresMenu = uimenu(app.VisualOptionsMenu);
            app.CloseAllFiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAllFiguresButtonPushed, true);
            app.CloseAllFiguresMenu.Text = 'Close All Figures';
            app.HistogramMenu = uimenu(app.VisualOptionsMenu);
            app.HistogramMenu.Text = 'Histogram';
            app.camcontrols = uimenu(app.VisualOptionsMenu);
            app.camcontrols.MenuSelectedFcn = createCallbackFcn(app, @camcontrolsSelected, true);
            app.camcontrols.Text = 'Camera controls';
            app.CalculationOptionsMenu = uimenu(app.Menu);
            app.CalculationOptionsMenu.Text = 'Calculation Options';
            app.mechanicalMenu = uimenu(app.CalculationOptionsMenu);
            app.mechanicalMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.mechanicalMenu.Text = 'Mechanical Homogenisation';
            app.CurvatureMethodMenu = uimenu(app.CalculationOptionsMenu);
            app.CurvatureMethodMenu.Separator = 'on';
            app.CurvatureMethodMenu.Text = 'Curvature Method';
            app.noneMenu = uimenu(app.CurvatureMethodMenu);
            app.noneMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.noneMenu.Tooltip = {'Do not calculate curvature (or derived) properties'};
            app.noneMenu.Checked = 'on';
            app.noneMenu.Text = 'none';
            app.KroonMenu = uimenu(app.CurvatureMethodMenu);
            app.KroonMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.KroonMenu.Text = 'Kroon';
            app.trimesh2Menu = uimenu(app.CurvatureMethodMenu);
            app.trimesh2Menu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.trimesh2Menu.Tooltip = {'Use the ''patch'' method for calculating curvature'};
            app.trimesh2Menu.Text = 'trimesh2';
            app.meyer2003Menu = uimenu(app.CurvatureMethodMenu);
            app.meyer2003Menu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.meyer2003Menu.Tooltip = {'Use the ''trimesh'' curvature method'};
            app.meyer2003Menu.Text = 'meyer2003';
            app.implicitMenu = uimenu(app.CurvatureMethodMenu);
            app.implicitMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.implicitMenu.Tooltip = {'Use the "implicit" curvature method'};
            app.implicitMenu.Text = 'implicit';
            app.GridLayout = uigridlayout(app.TPMSDesignerGUIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLeft = uigridlayout(app.LeftPanel);
            app.GridScrollable = uigridlayout(app.GridLeft);
            app.DesignSizeandResolutionPanel = uipanel(app.GridScrollable);
            app.DesignSizeandResolutionPanel.Title = 'Design Size and Resolution';
            app.GridDesignSizeandResolution = uigridlayout(app.DesignSizeandResolutionPanel);
            app.SizeMethodDropDownLabel = uilabel(app.GridDesignSizeandResolution);
            app.SizeMethodDropDownLabel.Text = 'Size Method';
            app.ResolutionvoxedSizeLabel_2 = uilabel(app.GridDesignSizeandResolution);
            app.ResolutionvoxedSizeLabel_2.Tooltip = {'Resolution, defined by the number of voxels.'};
            app.ResolutionvoxedSizeLabel_2.Text = 'nodes per edge';
            app.ResolutionvoxedSizeLabel = uilabel(app.GridDesignSizeandResolution);
            app.ResolutionvoxedSizeLabel.Tooltip = {'Resolution, defined by the number of voxels.'};
            app.ResolutionvoxedSizeLabel.Text = 'voxelSize';
            app.voxSize = uieditfield(app.GridDesignSizeandResolution, 'numeric');
            app.voxSize.Limits = [0 Inf];
            app.voxSize.ValueDisplayFormat = '%.3f';
            app.voxSize.Editable = 'off';
            app.voxSize.Tooltip = {'Voxel size (derived from nodes per edge, and bulk size/number of cells).'};
            app.voxSize.Value = 1;
            app.res = uieditfield(app.GridDesignSizeandResolution, 'numeric');
            app.res.Limits = [2 200];
            app.res.RoundFractionalValues = 'on';
            app.res.ValueDisplayFormat = '%.0f';
            app.res.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.res.Tooltip = {'Resolution of the structure defined by number of nodes per edge'};
            app.res.Value = 30;
            app.filename = uilabel(app.GridDesignSizeandResolution);
            app.filename.Visible = 'off';
            app.filename.Text = 'icosphere';
            app.LoadstlButton = uibutton(app.GridDesignSizeandResolution, 'push');
            app.LoadstlButton.ButtonPushedFcn = createCallbackFcn(app, @importSTL, true);
            app.LoadstlButton.Visible = 'off';
            app.LoadstlButton.Tooltip = {'Load a new stl file'};
            app.LoadstlButton.Text = 'Load .stl';
            app.Ly = uieditfield(app.GridDesignSizeandResolution, 'numeric');
            app.Ly.LowerLimitInclusive = 'off';
            app.Ly.UpperLimitInclusive = 'off';
            app.Ly.Limits = [0 Inf];
            app.Ly.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.Ly.Visible = 'off';
            app.Ly.Tooltip = {'Bounding box length y'};
            app.Ly.Value = 30;
            app.Lz = uieditfield(app.GridDesignSizeandResolution, 'numeric');
            app.Lz.LowerLimitInclusive = 'off';
            app.Lz.UpperLimitInclusive = 'off';
            app.Lz.Limits = [0 Inf];
            app.Lz.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.Lz.Visible = 'off';
            app.Lz.Tooltip = {'Bounding box length z'};
            app.Lz.Value = 30;
            app.Lx = uieditfield(app.GridDesignSizeandResolution, 'numeric');
            app.Lx.LowerLimitInclusive = 'off';
            app.Lx.UpperLimitInclusive = 'off';
            app.Lx.Limits = [0 Inf];
            app.Lx.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.Lx.Visible = 'off';
            app.Lx.Tooltip = {'Bounding box length x'};
            app.Lx.Value = 30;
            app.nz = uieditfield(app.GridDesignSizeandResolution, 'numeric');
            app.nz.LowerLimitInclusive = 'off';
            app.nz.UpperLimitInclusive = 'off';
            app.nz.Limits = [0 Inf];
            app.nz.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.nz.Tooltip = {'n cells in z'};
            app.nz.Value = 1;
            app.ny = uieditfield(app.GridDesignSizeandResolution, 'numeric');
            app.ny.LowerLimitInclusive = 'off';
            app.ny.UpperLimitInclusive = 'off';
            app.ny.Limits = [0 Inf];
            app.ny.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.ny.Tooltip = {'n cells in y'};
            app.ny.Value = 1;
            app.nx = uieditfield(app.GridDesignSizeandResolution, 'numeric');
            app.nx.LowerLimitInclusive = 'off';
            app.nx.UpperLimitInclusive = 'off';
            app.nx.Limits = [0 Inf];
            app.nx.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.nx.Tooltip = {'n cells in x'};
            app.nx.Value = 1;
            app.NxyzLabel = uilabel(app.GridDesignSizeandResolution);
            app.NxyzLabel.Tooltip = {'Bulk size definition:'};
            app.NxyzLabel.Text = 'N (x,y,z)';
            app.SizeMethodDropDown = uidropdown(app.GridDesignSizeandResolution);
            app.SizeMethodDropDown.Items = {'unitcell', 'box', 'cylinder', 'stl'};
            app.SizeMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.SizeMethodDropDown.Tooltip = {'Change the method for defining the bulk volume to generate.'; ''; 'unitcell - Set the number of unit cells in each direction'; 'box - fill a box with edge length Lx,Ly,Lz'; 'cylinder - cylinder with Rx, Ry, Lz'; 'stl - Use an stl mesh to trim the structure'};
            app.SizeMethodDropDown.Value = 'unitcell';
            app.TransformationsPanel = uipanel(app.GridScrollable);
            app.TransformationsPanel.Title = 'Transformations';
            app.GridTransformations = uigridlayout(app.TransformationsPanel);
            app.rx = uieditfield(app.GridTransformations, 'numeric');
            app.rx.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.rx.Tooltip = {'Rx (deg)'};
            app.ry = uieditfield(app.GridTransformations, 'numeric');
            app.ry.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.ry.Tooltip = {'Ry (deg)'};
            app.rz = uieditfield(app.GridTransformations, 'numeric');
            app.rz.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.rz.Tooltip = {'Rz (deg)'};
            app.RotationsdegLabel = uilabel(app.GridTransformations);
            app.RotationsdegLabel.Tooltip = {'Euler rotations of cellular structure (Rx,Ry,Rz). '; ''; '*Applying rotations will cause the homogenisation method to fail.'};
            app.RotationsdegLabel.Text = 'Rotations (deg)';
            app.bz = uieditfield(app.GridTransformations, 'numeric');
            app.bz.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.bz.Tooltip = {'Bz'};
            app.by = uieditfield(app.GridTransformations, 'numeric');
            app.by.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.by.Tooltip = {'By'};
            app.bx = uieditfield(app.GridTransformations, 'numeric');
            app.bx.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.bx.Tooltip = {'Bx'};
            app.PhaseShiftLabel = uilabel(app.GridTransformations);
            app.PhaseShiftLabel.Tooltip = {'Phase shift in x,y,z'};
            app.PhaseShiftLabel.Text = 'Phase Shift';
            app.linkl = uibutton(app.GridTransformations, 'state');
            app.linkl.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.linkl.Tooltip = {'Link scale values.'};
            app.linkl.Icon = fullfile(pathToMLAPP, 'link_icon.png');
            app.linkl.Text = '';
            app.linkl.Value = true;
            app.lz = uieditfield(app.GridTransformations, 'numeric');
            app.lz.LowerLimitInclusive = 'off';
            app.lz.UpperLimitInclusive = 'off';
            app.lz.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.lz.Enable = 'off';
            app.lz.Tooltip = {'lz'};
            app.lz.Value = 30;
            app.ly = uieditfield(app.GridTransformations, 'numeric');
            app.ly.LowerLimitInclusive = 'off';
            app.ly.UpperLimitInclusive = 'off';
            app.ly.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.ly.Enable = 'off';
            app.ly.Tooltip = {'ly'};
            app.ly.Value = 30;
            app.lx = uieditfield(app.GridTransformations, 'numeric');
            app.lx.LowerLimitInclusive = 'off';
            app.lx.UpperLimitInclusive = 'off';
            app.lx.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.lx.Tooltip = {'lx'};
            app.lx.Value = 30;
            app.UnitCellSizeLabel = uilabel(app.GridTransformations);
            app.UnitCellSizeLabel.Tooltip = {'Unit Cell Size in (x,y,z)'};
            app.UnitCellSizeLabel.Text = 'Unit Cell Size';
            app.GeneralEquationPanel = uipanel(app.GridScrollable);
            app.GeneralEquationPanel.Title = 'General / Equation';
            app.GridGeneral = uigridlayout(app.GeneralEquationPanel);
            app.TypeDropDownLabel = uilabel(app.GridGeneral);
            app.TypeDropDownLabel.Tooltip = {''};
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.GridGeneral);
            app.TypeDropDown.Items = {'network', 'surface', 'single', 'double', 'lattice'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.TypeDropDown.Tooltip = {'Select the type of TPMS you wish to generate.'; ''; 'network: Produces a skeletal structure using a target volume fraction as the input the isovalue v1 is defined to achieve close to the target volume fraction. (Finds volume with: U ≤ v1).'; ''; 'surface: Produces a surface with midplane on the zero isosurface at a target volume fraction. This calculates the isovalues +v1 and -v1.'; '(Finds volume with: -v1 ≤ U ≤ v1)'; ''; 'single: Similar to network, but procudes a result directly from the isovalue'; ''; 'double: General form which directly finds the volume bounded by two isovalues v1 and v2.'; '(Finds volume with: v1 ≤ U ≤ v2)'; ''; 'lattice: Select a pre-defined lattice definition of nodes and connectivity, then thicken to generate the structure'};
            app.TypeDropDown.Value = 'network';
            app.EquationDropDownLabel = uilabel(app.GridGeneral);
            app.EquationDropDownLabel.Tooltip = {''};
            app.EquationDropDownLabel.Text = 'Equation';
            app.EquationDropDown = uidropdown(app.GridGeneral);
            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Sinusoidal', 'Lidinoid', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
            app.EquationDropDown.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.EquationDropDown.Tooltip = {'Select the equation you wish to generate.The most common TPMS (Gyroid, Diamond, Primitive) have been included along with some sample implicit objects (Sinusoidal, Sphere, PnormCube, Taurus).'; ''; '"Custom" to modify or write a new equation.'};
            app.EquationDropDown.Value = 'Gyroid';
            app.v1Label = uilabel(app.GridGeneral);
            app.v1Label.Text = 'Target Volume Fraction';
            app.v1 = uieditfield(app.GridGeneral, 'numeric');
            app.v1.ValueDisplayFormat = '%11.2g';
            app.v1.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.v1.Tooltip = {'Geometry Property 1'};
            app.v1.Value = 0.25;
            app.v2Label = uilabel(app.GridGeneral);
            app.v2Label.Text = '';
            app.v2 = uieditfield(app.GridGeneral, 'numeric');
            app.v2.ValueDisplayFormat = '%11.2g';
            app.v2.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.v2.Visible = 'off';
            app.v2.Tooltip = {'Geometry Value 2'};
            app.volLabel = uilabel(app.GridGeneral);
            app.volLabel.Text = 'Calculated Volume Fraction';
            app.vol = uieditfield(app.GridGeneral, 'numeric');
            app.vol.Limits = [0 1];
            app.vol.Editable = 'off';
            app.vol.Tooltip = {'Volume fraction calculated for the voxelated model (between 0 and 1)'};
            app.EquationText = uitextarea(app.GridGeneral);
            app.EquationText.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.EquationText.Tooltip = {'Displays the equation for the current structure.'; 'Can be edited with your custom function here. '; 'Custom functions must be a valid matlab function with correcct format for example: @(x,y,z) x.*y+z-3*x.'};
            app.EquationText.Value = {'Equation'};
            app.volumeSlider = uislider(app.GridGeneral);
            app.volumeSlider.Limits = [0 1];
            app.volumeSlider.ValueChangingFcn = createCallbackFcn(app, @changeCell, true);
            app.volumeSlider.Value = 0.25;
            app.GridUpdate = uigridlayout(app.GridLeft);
            app.AutoUpdateSwitchLabel = uilabel(app.GridUpdate);
            app.AutoUpdateSwitchLabel.Text = 'Auto-Update';
            app.ProcessDataButton = uibutton(app.GridUpdate, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @updateButton, true);
            app.ProcessDataButton.Text = 'Update';
            app.ResetButton = uibutton(app.GridUpdate, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButton, true);
            app.ResetButton.Text = 'Reset';
            app.AutoUpdateSwitch = uiswitch(app.GridUpdate, 'slider');
            app.AutoUpdateSwitch.ItemsData = [0 1];
            app.AutoUpdateSwitch.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridRight = uigridlayout(app.RightPanel);
            app.ax = uiaxes(app.GridRight);
            xlabel(app.ax, 'X')
            ylabel(app.ax, 'Y')
            app.ax.XTickLabelRotationMode = 'manual';
            app.ax.YTickLabelRotationMode = 'manual';
            app.ax.ZTickLabelRotationMode = 'manual';
            colormap(app.ax, 'turbo')
            app.GridFigureOptions2 = uigridlayout(app.GridRight);
            app.statusText = uilabel(app.GridFigureOptions2);
            app.statusText.Text = 'Initialising';
            app.sliderLabel = uilabel(app.GridFigureOptions2);
            app.sliderLabel.Visible = 'off';
            app.sliderLabel.Text = 'Z-Height';
            app.polemapNLabel = uilabel(app.GridFigureOptions2);
            app.polemapNLabel.Visible = 'off';
            app.polemapNLabel.Text = 'Polemap divisions';
            app.polemapN = uispinner(app.GridFigureOptions2);
            app.polemapN.Limits = [1 5];
            app.polemapN.RoundFractionalValues = 'on';
            app.polemapN.ValueChangedFcn = createCallbackFcn(app, @changeOptions, true);
            app.polemapN.Visible = 'off';
            app.polemapN.Tooltip = {'Number of division of the icosphere used for the polemap. (More divisions will require higher resolution meshing for good results)'};
            app.polemapN.Value = 3;
            app.sliderUI = uislider(app.GridFigureOptions2);
            app.sliderUI.MajorTicks = [0 50 100];
            app.sliderUI.MajorTickLabels = {''};
            app.sliderUI.ValueChangingFcn = createCallbackFcn(app, @slicesliderUIChanging, true);
            app.sliderUI.Visible = 'off';
            app.histogramN = uispinner(app.GridFigureOptions2);
            app.histogramN.Step = 10;
            app.histogramN.Limits = [1 500];
            app.histogramN.RoundFractionalValues = 'on';
            app.histogramN.ValueChangedFcn = createCallbackFcn(app, @changeOptions, true);
            app.histogramN.Visible = 'off';
            app.histogramN.Tooltip = {'Number of division of the icosphere used for the polemap. (More divisions will require higher resolution meshing for good results)'};
            app.histogramN.Value = 100;
            app.ExportfigButton = uibutton(app.GridFigureOptions2, 'push');
            app.ExportfigButton.ButtonPushedFcn = createCallbackFcn(app, @exportFig, true);
            app.ExportfigButton.Text = 'Export .fig';
            app.GridFigureOptions1 = uigridlayout(app.GridRight);
            app.typeText = uilabel(app.GridFigureOptions1);
            app.typeText.Text = 'Plot type';
            app.FacePropertyLabel = uilabel(app.GridFigureOptions1);
            app.FacePropertyLabel.Text = 'Property';
            app.FaceProperty2Label = uilabel(app.GridFigureOptions1);
            app.FaceProperty2Label.Text = 'Property 2';
            app.type = uidropdown(app.GridFigureOptions1);
            app.type.Items = {'Surface Mesh', 'Field viewer', 'voxel', 'histogram', 'histogram2', 'polemap', 'tensor', 'Metrics Scatterplot'};
            app.type.ItemsData = {'surfaceMesh', 'field viewer', 'voxel', 'histogram', 'histogram2', 'polemap', 'tensor', 'metrics scatterplot'};
            app.type.ValueChangedFcn = createCallbackFcn(app, @changeVisulisation, true);
            app.type.Tooltip = {'Visualisation types:'; ''; 'surfaceMesh - Mesh coloured by a selected property'; 'voxel - Voxelated model coloured by selected property'; 'histogram - Shows distribution of a given property'; 'histogram2 - Shows distribution of 2-property combinations'; 'polemap - Mapping of surface orientations onto a sphere'; 'field viewer - Show the value of a field at various Z-slices'; 'tensor - Show a visualisation of the cauchy stiffness tensor'; 'metric history - Shows the historically saved metric data in an xy scatterplot'};
            app.type.Value = 'surfaceMesh';
            app.P1 = uidropdown(app.GridFigureOptions1);
            app.P1.Items = {'inclination'};
            app.P1.ValueChangedFcn = createCallbackFcn(app, @changeOptions, true);
            app.P1.Value = 'inclination';
            app.P2 = uidropdown(app.GridFigureOptions1);
            app.P2.Items = {'none'};
            app.P2.ValueChangedFcn = createCallbackFcn(app, @changeOptions, true);
            app.P2.Value = 'none';
            % Show the figure after all components are created
            app.TPMSDesignerGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TPMS_Designer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TPMSDesignerGUIUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TPMSDesignerGUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TPMSDesignerGUIUIFigure)
        end
    end
end